classdef RoverApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure       matlab.ui.Figure
        AngleLabel     matlab.ui.control.Label
        VelocityLabel  matlab.ui.control.Label
        Switch         matlab.ui.control.ToggleSwitch
        EStop          matlab.ui.control.Button
        AngleWaveAxes  matlab.ui.control.UIAxes
        VelWaveAxes    matlab.ui.control.UIAxes
        PreviewAxes    matlab.ui.control.UIAxes
        LidarAxes      matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        moj;
        running;
    end
    
    methods (Access = private)
        
        function stop_mojave(app)
            app.running = false;
            app.moj.set_speed(0);
            app.moj.lidar.lidar_shutdown();
            clear app.moj;
            app.VelocityLabel.Text = "Velocity: ";
            app.AngleLabel.Text = "Angle: ";
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: Switch
        function SwitchValueChanged(app, event)
            value = app.Switch.Value;
            if value == "On"
                addpath brainwaves\
                addpath color_masking\
                addpath april_tags\
                addpath lidar\
                addpath utils\

                app.moj = get_mojave();
                app.moj.lidar.lidar_setup();

                snap = app.moj.sense_cam();
                img = image(app.PreviewAxes, zeros(size(snap)));
                preview(img, app.moj.robot_cam);
                corner_data = plot(app.PreviewAxes, 0, 0, 'om');
                lidar_data = plot(app.LidarAxes, 0, 0, '.b');

                lidar_func = @(m) lidar_wave(m);
                path_func = @(m) follow_color(m, @(i) beige_mask(i));
                blue_func = @(m) follow_color(m, @(i) blue_mask(i));
                inner_func = @(m) inner_o_wave(m);
                wave_funcs = {lidar_func, path_func, blue_func, inner_func};
                arb = arbiter(wave_funcs, linspace(0, 1, 13), -30:5:30, app.moj);

                app.running = true;
                while app.running
                    [vwaves, wwaves] = arb.get_waves();
                    [v, w] = arb.arbitrate_from_waves(sum(vwaves), sum(wwaves));
                    
                    cla(app.VelWaveAxes);
                    cla(app.AngleWaveAxes);
                    plot(app.VelWaveAxes, arb.vel_result_vec, vwaves);
                    plot(app.AngleWaveAxes, arb.ang_result_vec, wwaves);

                    tags = app.moj.find_april_tags();
                    corner_data.XData = zeros(1, length(tags) * 4);
                    corner_data.YData = zeros(1, length(tags) * 4);
                    for i = 1:length(tags)
                        corner_data.XData(4*i:4*i+3) = tags(i).corners(:, 1);
                        corner_data.YData(4*i:4*i+3) = tags(i).corners(:, 2);
                    end

                    scan = app.moj.lidar_scan_single();
                    lidar_data.XData = scan.Cartesian(:, 1);
                    lidar_data.YData = scan.Cartesian(:, 2);

                    app.VelocityLabel.Text = "Velocity: " + v;
                    app.AngleLabel.Text = "Angle: " + w;

                    app.moj.set_speed(v);
                    app.moj.steer(w);
                end
                app.moj.set_speed(0);
            elseif value == "Off"
                app.stop_mojave();
            end
        end

        % Button pushed function: EStop
        function EStopPushed(app, event)
            app.stop_mojave();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.LidarAxes = uiaxes(app.UIFigure);
            title(app.LidarAxes, 'LiDAR')
            zlabel(app.LidarAxes, 'Z')
            app.PreviewAxes = uiaxes(app.UIFigure);
            title(app.PreviewAxes, 'Cam Preview')
            zlabel(app.PreviewAxes, 'Z')
            app.VelWaveAxes = uiaxes(app.UIFigure);
            title(app.VelWaveAxes, 'Velocity Brainwave')
            xlabel(app.VelWaveAxes, 'Speed')
            ylabel(app.VelWaveAxes, 'Wave')
            zlabel(app.VelWaveAxes, 'Z')
            app.VelWaveAxes.YLim = [0 10];
            app.AngleWaveAxes = uiaxes(app.UIFigure);
            title(app.AngleWaveAxes, 'Angle Brainwave')
            xlabel(app.AngleWaveAxes, 'Speed')
            ylabel(app.AngleWaveAxes, 'Wave')
            zlabel(app.AngleWaveAxes, 'Z')
            app.AngleWaveAxes.YLim = [0 10];
            app.EStop = uibutton(app.UIFigure, 'push');
            app.EStop.ButtonPushedFcn = createCallbackFcn(app, @EStopPushed, true);
            app.EStop.Icon = 'estopbutton.png';
            app.EStop.Text = '';
            app.Switch = uiswitch(app.UIFigure, 'toggle');
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.VelocityLabel = uilabel(app.UIFigure);
            app.VelocityLabel.Text = 'Velocity:';
            app.AngleLabel = uilabel(app.UIFigure);
            app.AngleLabel.Text = 'Angle:';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = RoverApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end