classdef ColorMaskTesting < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        CCMButton          matlab.ui.control.Button
        StopButton         matlab.ui.control.Button
        StartButton        matlab.ui.control.Button
        CurrentImageLabel  matlab.ui.control.Label
        LastImageLabel     matlab.ui.control.Label
        SnapshotButton     matlab.ui.control.Button
        GreenLabel         matlab.ui.control.Label
        GrassLabel         matlab.ui.control.Label
        GrayLabel          matlab.ui.control.Label
        PurpleLabel        matlab.ui.control.Label
        BeigeLabel         matlab.ui.control.Label
        Preview            matlab.ui.control.UIAxes
        GrayImage          matlab.ui.control.UIAxes
        GreenImage         matlab.ui.control.UIAxes
        GrassImage         matlab.ui.control.UIAxes
        BeigeImage         matlab.ui.control.UIAxes
        PurpleImage        matlab.ui.control.UIAxes
        LastImage          matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        cam;    % Camera object
        ccm;    % color correction matrix
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            app.cam = get_webcam(2);
            app.CCMButton.Visible = true;
            app.StartButton.Visible = false;
            app.StopButton.Visible = true;
            snap = snapshot(app.cam);

            app.Preview.XLim = [0, size(snap, 2)];
            app.Preview.YLim = [0, size(snap, 1)];
            app.LastImage.XLim = [0, size(snap, 2)];
            app.LastImage.YLim = [0, size(snap, 1)];
            app.BeigeImage.XLim = [0, size(snap, 2)];
            app.BeigeImage.YLim = [0, size(snap, 1)];
            app.PurpleImage.XLim = [0, size(snap, 2)];
            app.PurpleImage.YLim = [0, size(snap, 1)];
            app.GrayImage.XLim = [0, size(snap, 2)];
            app.GrayImage.YLim = [0, size(snap, 1)];
            app.GrassImage.XLim = [0, size(snap, 2)];
            app.GrassImage.YLim = [0, size(snap, 1)];
            app.GreenImage.XLim = [0, size(snap, 2)];
            app.GreenImage.YLim = [0, size(snap, 1)];

            img = image(app.Preview, zeros(size(snap)));
            preview(app.cam, img);
        end

        % Button pushed function: SnapshotButton
        function SnapshotButtonPushed(app, event)
            % Take picture, put on last cam
            img = snapshot(app.cam);
            img = apply_color_correction(img, app.ccm);
            plot_image(app.LastImage, img);
            
            % Plot masks
            plot_image(app.BeigeImage, beige_mask(img));
            plot_image(app.PurpleImage, purple_mask(img));
            plot_image(app.GrayImage, gray_mask(img));
            plot_image(app.GrassImage, grass_mask(img));
            plot_image(app.GreenImage, green_mask(img));
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            app.StopButton.Visible = false;
            app.SnapshotButton.Visible = false;
            app.StartButton.Visible = true;

            closePreview(app.cam);
            image(app.Preview, zeros(size(snapshot(app.cam))));
        end

        % Button pushed function: CCMButton
        function CCMButtonPushed(app, event)
            img = snapshot(app.cam);
            app.ccm = color_correction_matrix(img);

            app.CCMButton.Visible = false;
            app.SnapshotButton.Visible = true;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.LastImage = uiaxes(app.UIFigure);
            zlabel(app.LastImage, 'Z')
            app.LastImage.XTick = [];
            app.LastImage.YTick = [];
            app.PurpleImage = uiaxes(app.UIFigure);
            zlabel(app.PurpleImage, 'Z')
            app.PurpleImage.XTick = [];
            app.PurpleImage.YTick = [];
            app.BeigeImage = uiaxes(app.UIFigure);
            zlabel(app.BeigeImage, 'Z')
            app.BeigeImage.XTick = [];
            app.BeigeImage.YTick = [];
            app.GrassImage = uiaxes(app.UIFigure);
            zlabel(app.GrassImage, 'Z')
            app.GrassImage.XTick = [];
            app.GrassImage.YTick = [];
            app.GreenImage = uiaxes(app.UIFigure);
            zlabel(app.GreenImage, 'Z')
            app.GreenImage.XTick = [];
            app.GreenImage.YTick = [];
            app.GrayImage = uiaxes(app.UIFigure);
            zlabel(app.GrayImage, 'Z')
            app.GrayImage.XTick = [];
            app.GrayImage.YTick = [];
            app.Preview = uiaxes(app.UIFigure);
            zlabel(app.Preview, 'Z')
            app.Preview.XTick = [];
            app.Preview.YTick = [];
            app.BeigeLabel = uilabel(app.UIFigure);
            app.BeigeLabel.Text = 'Beige';
            app.PurpleLabel = uilabel(app.UIFigure);
            app.PurpleLabel.Text = 'Purple';
            app.GrayLabel = uilabel(app.UIFigure);
            app.GrayLabel.Text = 'Gray';
            app.GrassLabel = uilabel(app.UIFigure);
            app.GrassLabel.Text = 'Grass';
            app.GreenLabel = uilabel(app.UIFigure);
            app.GreenLabel.Text = 'Green';
            app.SnapshotButton = uibutton(app.UIFigure, 'push');
            app.SnapshotButton.ButtonPushedFcn = createCallbackFcn(app, @SnapshotButtonPushed, true);
            app.SnapshotButton.Visible = 'off';
            app.SnapshotButton.Text = 'Take Snapshot';
            app.LastImageLabel = uilabel(app.UIFigure);
            app.LastImageLabel.Text = 'Last Image';
            app.CurrentImageLabel = uilabel(app.UIFigure);
            app.CurrentImageLabel.Text = 'Current Image';
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.StopButton = uibutton(app.UIFigure, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Visible = 'off';
            app.StopButton.Text = 'Stop';
            app.CCMButton = uibutton(app.UIFigure, 'push');
            app.CCMButton.ButtonPushedFcn = createCallbackFcn(app, @CCMButtonPushed, true);
            app.CCMButton.Visible = 'off';
            app.CCMButton.Text = 'Color Correct';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ColorMaskTesting

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end