classdef ArchApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        CurrentAngleLabel  matlab.ui.control.Label
        EStopButton        matlab.ui.control.Button
        Switch             matlab.ui.control.Switch
        SwitchLabel        matlab.ui.control.Label
        CamPreview         matlab.ui.control.UIAxes
        BrainwaveAxes      matlab.ui.control.UIAxes
        LidarAxes          matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        mojave % Description
        running = false;
        initialized = false;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function
        function UIAxesButtonDown(app, event)
            
        end

        % Button pushed function: EStopButton
        function EStopButtonPushed(app, event)
            app.mojave.set_speed(0);
            app.running = false;
        end

        % Value changed function: Switch
        function SwitchValueChanged(app, event)
            value = app.Switch.Value;
            if value == "On"
                if ~app.initialized
                    addpath ..
                    addpath ../lidar
                    app.mojave = get_mojave();
                    addpath ../brainwaves
                    addpath ../color_masking
                    addpath ../april_tags
                    app.mojave.lidar.lidar_setup();
                    app.initialized = true;

                    snap = app.mojave.sense_cam();
                    img = image(app.CamPreview, zeros(size(snap)));
                    preview(app.mojave.robot_cam, img);

                    data = plot(app.CamPreview, 0, 0, 'om');
                end
                oarbiter = arbiter({@(m) lidar_arch(m)}, linspace(0, 1, 13), linspace(-30, 30, 13), app.mojave);
                tic;
                app.running = true;
                while toc < 30 && app.running
                    [vwave, wwave] = oarbiter.get_waves();
%                     app.CurrentAngleLabel.Text = mat2str(wwave);
                    plot(app.BrainwaveAxes, oarbiter.ang_result_vec, wwave);
                    addpath ../april_tags
                    tags = app.mojave.find_april_tags();
                    cla(app.CamPreview);
                    for tag = tags
                        data.XData = tag.corners(:, 1);
                        data.YData = tag.corners(:, 2);
                    end
                    scan = app.mojave.lidar.lidar_scan_single();
                    plot(app.LidarAxes, scan.Cartesian(:, 1), scan.Cartesian(:, 2), '.b');
                    [v, w] = oarbiter.arbitrate_from_waves(sum(vwave), sum(wwave));
                    app.CurrentAngleLabel.Text = "Current Angle: " + w;
%                     app.mojave.set_speed(v);
%                     app.mojave.steer(w);
                end
            end 
        end

        % Callback function
        function UIAxesButtonDown2(app, event)
            
        end

        % Button down function: CamPreview
        function CamPreviewButtonDown(app, event)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.LidarAxes = uiaxes(app.UIFigure);
            title(app.LidarAxes, 'LiDAR Scan')
            xlabel(app.LidarAxes, 'X')
            ylabel(app.LidarAxes, 'Y')
            zlabel(app.LidarAxes, 'Z')
            app.BrainwaveAxes = uiaxes(app.UIFigure);
            title(app.BrainwaveAxes, 'Ang Vel')
            xlabel(app.BrainwaveAxes, 'X')
            ylabel(app.BrainwaveAxes, 'Y')
            zlabel(app.BrainwaveAxes, 'Z')
            app.CamPreview = uiaxes(app.UIFigure);
            title(app.CamPreview, 'Camera Preview')
            xlabel(app.CamPreview, 'X')
            ylabel(app.CamPreview, 'Y')
            zlabel(app.CamPreview, 'Z')
            app.CamPreview.ButtonDownFcn = createCallbackFcn(app, @CamPreviewButtonDown, true);
            app.SwitchLabel = uilabel(app.UIFigure);
            app.SwitchLabel.Text = 'Switch';
            app.Switch = uiswitch(app.UIFigure, 'slider');
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.EStopButton = uibutton(app.UIFigure, 'push');
            app.EStopButton.ButtonPushedFcn = createCallbackFcn(app, @EStopButtonPushed, true);
            app.EStopButton.Text = 'E-Stop';
            app.CurrentAngleLabel = uilabel(app.UIFigure);
            app.CurrentAngleLabel.Text = 'Current Angle: ';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ArchApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end